classdef Opto_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        MLEditField                  matlab.ui.control.NumericEditField
        MLEditFieldLabel             matlab.ui.control.Label
        APEditField                  matlab.ui.control.NumericEditField
        APEditFieldLabel             matlab.ui.control.Label
        OnetrialButton               matlab.ui.control.Button
        SetBregmaButton              matlab.ui.control.Button
        EnvelopeButtonGroup          matlab.ui.container.ButtonGroup
        RampDownButton               matlab.ui.control.RadioButton
        GaussianButton               matlab.ui.control.RadioButton
        NoneButton_2                 matlab.ui.control.RadioButton
        PulseShapeButtonGroup        matlab.ui.container.ButtonGroup
        CosineButton                 matlab.ui.control.RadioButton
        SquareButton                 matlab.ui.control.RadioButton
        GridDropDown                 matlab.ui.control.DropDown
        GridDropDownLabel            matlab.ui.control.Label
        FrequencyHzEditField         matlab.ui.control.NumericEditField
        FrequencyHzEditFieldLabel    matlab.ui.control.Label
        DurationmsEditField          matlab.ui.control.NumericEditField
        DurationmsEditFieldLabel     matlab.ui.control.Label
        DutycycletimeEditField       matlab.ui.control.NumericEditField
        DutycycletimeEditFieldLabel  matlab.ui.control.Label
        AmplitudemVEditField         matlab.ui.control.NumericEditField
        AmplitudemVEditFieldLabel    matlab.ui.control.Label
        SetParamsButton              matlab.ui.control.Button
        OptogeneticsLabel            matlab.ui.control.Label
        UIAxes2                      matlab.ui.control.UIAxes
        UIAxes                       matlab.ui.control.UIAxes
    end

    properties (Access = public)

        grid = '';
        AP_coord    = [];
        ML_coord    = [];
        amplitude   = [];
        pulse_width = [];
        duration    = [];
        frequency   = [];
        pulse_shape = '';
        envelope    = '';

    end
    methods (Access = private)
        
        function results = update_opto_info(app)
            app.grid            = app.GridDropDown.Value;
            app.AP_coord        = app.APEditField.Value;
            app.ML_coord        = app.MLEditField.Value;
            app.amplitude       = app.AmplitudemVEditField.Value;
            app.duty_cycle      = app.DutycycletimeEditField.Value/100;
            app.duration        = app.DurationmsEditField.Value;
            app.frequency       = app.FrequencyHzEditField.Value;

            if app.CosineButton.Value
                app.pulse_shape = 'cosine';
            elseif app.SquareButton.Value
                app.pulse_shape = 'square';
            end

            if app.NoneButton_2.Value
                app.envelope    = 'None';
            elseif app.GaussianButton.Value
                app.envelope    = 'Gaussian';
            elseif app.RampDownButton.Value
                app.envelope    = 'Ramp';
            end

            global Opto_info
            Opto_info.grid          = app.grid;
            Opto_info.AP_coord      = app.AP_coord;
            Opto_info.ML_coord      = app.ML_coord;
            Opto_info.amplitude     = app.amplitude;
            Opto_info.duty_cycle    = app.duty_cycle;
            Opto_info.duration      = app.duration;
            Opto_info.frequency     = app.frequency;
            Opto_info.pulse_shape   = app.pulse_shape;
            Opto_info.envelope      = app.envelope;

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SetParamsButton
        function SetParamsButtonPushed(app, event)
            update_opto_info(app);
        end

        % Value changed function: AmplitudemVEditField
        function AmplitudemVEditFieldValueChanged(app, event)
            value = app.AmplitudemVEditField.Value;
            update_opto_info(app);
        end

        % Value changed function: DutycycletimeEditField
        function DutycycletimeEditFieldValueChanged(app, event)
            value = app.DutycycletimeEditField.Value;
            update_opto_info(app);
        end

        % Value changed function: DurationmsEditField
        function DurationmsEditFieldValueChanged(app, event)
            value = app.DurationmsEditField.Value;
            update_opto_info(app);
        end

        % Value changed function: FrequencyHzEditField
        function FrequencyHzEditFieldValueChanged(app, event)
            value = app.FrequencyHzEditField.Value;
            update_opto_info(app);
        end

        % Value changed function: GridDropDown
        function GridDropDownValueChanged(app, event)
            value = app.GridDropDown.Value;
            update_opto_info(app);
        end

        % Value changed function: APEditField
        function APEditFieldValueChanged(app, event)
            value = app.APEditField.Value;
            update_opto_info(app);
        end

        % Value changed function: MLEditField
        function MLEditFieldValueChanged(app, event)
            value = app.MLEditField.Value;
            update_opto_info(app);
        end

        % Selection changed function: EnvelopeButtonGroup
        function EnvelopeButtonGroupSelectionChanged(app, event)
            selectedButton = app.EnvelopeButtonGroup.SelectedObject;
            update_opto_info(app);
        end

        % Selection changed function: PulseShapeButtonGroup
        function PulseShapeButtonGroupSelectionChanged(app, event)
            selectedButton = app.PulseShapeButtonGroup.SelectedObject;
            update_opto_info(app);
        end

        % Button pushed function: OnetrialButton
        function OnetrialButtonPushed(app, event)
            global Opto_S
            opto_setup
            trial_dur = 1;
            % Make laser vector
            t = 0:1/Opto_S.Rate:trial_dur; % time vector
            w = app.duty_cycle/app.frequency; % pulse width
            d = w/2 : 1/app.frequency : t(1:trial_dur/2);
            y = pulstran(t(1:end/2), d, 'rectpuls', w);
            opto_vec = [zeros(0.2*1000/Opto_S.Rate) y zeros((1-0.2-trial_dur/2))*1000/Opto_S.Rate)];

            % Move galvos into position
            
            Opto_S.write([opto_vec; galv_x; galv_y]')

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.OptogeneticsLabel = uilabel(app.UIFigure);
            app.OptogeneticsLabel.Text = 'Optogenetics';
            app.SetParamsButton = uibutton(app.UIFigure, 'push');
            app.SetParamsButton.ButtonPushedFcn = createCallbackFcn(app, @SetParamsButtonPushed, true);
            app.SetParamsButton.Text = 'Set Params';
            app.AmplitudemVEditFieldLabel = uilabel(app.UIFigure);
            app.AmplitudemVEditFieldLabel.Text = 'Amplitude (mV)';
            app.AmplitudemVEditField = uieditfield(app.UIFigure, 'numeric');
            app.AmplitudemVEditField.ValueChangedFcn = createCallbackFcn(app, @AmplitudemVEditFieldValueChanged, true);
            app.DutycycletimeEditFieldLabel = uilabel(app.UIFigure);
            app.DutycycletimeEditFieldLabel.Text = 'Duty cycle (% time)';
            app.DutycycletimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.DutycycletimeEditField.ValueChangedFcn = createCallbackFcn(app, @DutycycletimeEditFieldValueChanged, true);
            app.DurationmsEditFieldLabel = uilabel(app.UIFigure);
            app.DurationmsEditFieldLabel.Text = 'Duration (ms)';
            app.DurationmsEditField = uieditfield(app.UIFigure, 'numeric');
            app.DurationmsEditField.ValueChangedFcn = createCallbackFcn(app, @DurationmsEditFieldValueChanged, true);
            app.FrequencyHzEditFieldLabel = uilabel(app.UIFigure);
            app.FrequencyHzEditFieldLabel.Text = 'Frequency (Hz)';
            app.FrequencyHzEditField = uieditfield(app.UIFigure, 'numeric');
            app.FrequencyHzEditField.ValueChangedFcn = createCallbackFcn(app, @FrequencyHzEditFieldValueChanged, true);
            app.GridDropDownLabel = uilabel(app.UIFigure);
            app.GridDropDownLabel.Text = 'Grid';
            app.GridDropDown = uidropdown(app.UIFigure);
            app.GridDropDown.Items = {'No Grid', 'Grid 1', 'Grid 2', 'Grid 3'};
            app.GridDropDown.ValueChangedFcn = createCallbackFcn(app, @GridDropDownValueChanged, true);
            app.GridDropDown.Value = 'No Grid';
            app.PulseShapeButtonGroup = uibuttongroup(app.UIFigure);
            app.PulseShapeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PulseShapeButtonGroupSelectionChanged, true);
            app.PulseShapeButtonGroup.Title = 'Pulse Shape';
            app.SquareButton = uiradiobutton(app.PulseShapeButtonGroup);
            app.SquareButton.Text = 'Square';
            app.SquareButton.Value = true;
            app.CosineButton = uiradiobutton(app.PulseShapeButtonGroup);
            app.CosineButton.Text = 'Cosine';
            app.EnvelopeButtonGroup = uibuttongroup(app.UIFigure);
            app.EnvelopeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @EnvelopeButtonGroupSelectionChanged, true);
            app.EnvelopeButtonGroup.Title = 'Envelope';
            app.NoneButton_2 = uiradiobutton(app.EnvelopeButtonGroup);
            app.NoneButton_2.Text = 'None';
            app.NoneButton_2.Value = true;
            app.GaussianButton = uiradiobutton(app.EnvelopeButtonGroup);
            app.GaussianButton.Text = 'Gaussian';
            app.RampDownButton = uiradiobutton(app.EnvelopeButtonGroup);
            app.RampDownButton.Text = 'Ramp Down';
            app.SetBregmaButton = uibutton(app.UIFigure, 'push');
            app.SetBregmaButton.Enable = 'off';
            app.SetBregmaButton.Text = 'Set Bregma';
            app.OnetrialButton = uibutton(app.UIFigure, 'push');
            app.OnetrialButton.ButtonPushedFcn = createCallbackFcn(app, @OnetrialButtonPushed, true);
            app.OnetrialButton.Text = 'One trial';
            app.APEditFieldLabel = uilabel(app.UIFigure);
            app.APEditFieldLabel.Text = 'AP';
            app.APEditField = uieditfield(app.UIFigure, 'numeric');
            app.APEditField.ValueChangedFcn = createCallbackFcn(app, @APEditFieldValueChanged, true);
            app.MLEditFieldLabel = uilabel(app.UIFigure);
            app.MLEditFieldLabel.Text = 'ML';
            app.MLEditField = uieditfield(app.UIFigure, 'numeric');
            app.MLEditField.ValueChangedFcn = createCallbackFcn(app, @MLEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Opto_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end